classdef ELT_PSF_V5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        label_3D                    matlab.ui.control.Label
        label_custom_aperture       matlab.ui.control.Label
        label_template_aperture     matlab.ui.control.Label
        label_templatePSF           matlab.ui.control.Label
        label_customPSF             matlab.ui.control.Label
        logo                        matlab.ui.control.Image
        ContrastSlider              matlab.ui.control.Slider
        ContrastSliderLabel         matlab.ui.control.Label
        ColormapsButtonGroup        matlab.ui.container.ButtonGroup
        GrayButton                  matlab.ui.control.RadioButton
        HotButton                   matlab.ui.control.RadioButton
        JetButton                   matlab.ui.control.RadioButton
        ColorscaleButtonGroup       matlab.ui.container.ButtonGroup
        LogButton                   matlab.ui.control.RadioButton
        LinearButton                matlab.ui.control.RadioButton
        WavelengthEditField         matlab.ui.control.NumericEditField
        WavelengthSlider            matlab.ui.control.Slider
        WavelengthmumLabel          matlab.ui.control.Label
        TemplatesPanel              matlab.ui.container.Panel
        Reset                       matlab.ui.control.Button
        GMT                         matlab.ui.control.Image
        VLT                         matlab.ui.control.Image
        JWST                        matlab.ui.control.Image
        Keck                        matlab.ui.control.Image
        ELT                         matlab.ui.control.Image
        Hubble                      matlab.ui.control.Image
        ImportButton                matlab.ui.control.Button
        CustomPanel                 matlab.ui.container.Panel
        note                        matlab.ui.control.Label
        SpiderAngle                 matlab.ui.control.EditField
        AnglesLabel                 matlab.ui.control.Label
        HelpButton                  matlab.ui.control.Button
        SaveAperture                matlab.ui.control.Button
        CustomStatus                matlab.ui.control.EditField
        LoadpresetButton            matlab.ui.control.Button
        SavepresetButton            matlab.ui.control.Button
        Numberspiderseditfield      matlab.ui.control.NumericEditField
        SpidersCheckBox             matlab.ui.control.CheckBox
        SpidersCheckBoxLabel        matlab.ui.control.Label
        SpiderThicknessSlider       matlab.ui.control.Slider
        MirrorgeometryLabel         matlab.ui.control.Label
        MirrorTypeGroup             matlab.ui.container.ButtonGroup
        HexagonalButton             matlab.ui.control.RadioButton
        CircularButton              matlab.ui.control.RadioButton
        SegmentDistanceSlider       matlab.ui.control.Slider
        SegmentDistanceSliderLabel  matlab.ui.control.Label
        NumberofRingsEditField      matlab.ui.control.NumericEditField
        NumberofRingsLabel          matlab.ui.control.Label
        DiameterObstructionSlider   matlab.ui.control.Slider
        ObstructionLabel            matlab.ui.control.Label
        ObstructionCheckBox         matlab.ui.control.CheckBox
        TelescopeDropDown           matlab.ui.control.DropDown
        TelescopeDropDownLabel      matlab.ui.control.Label
        TemplateStatus              matlab.ui.control.EditField
        temp_aperture               matlab.ui.control.UIAxes
        custom_aperture             matlab.ui.control.UIAxes
        template_profile            matlab.ui.control.UIAxes
        template_aperture           matlab.ui.control.UIAxes
        PSF_3D                      matlab.ui.control.UIAxes
        template_PSF                matlab.ui.control.UIAxes
        custom_PSF                  matlab.ui.control.UIAxes
        custom_profile              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        lambda_ref = 600;   % reference wavelength  
        N = 2048; % grid size of the PSF at reference wavelength
        n = 4050; % grid size of templates
        aperture; % store custom aperture
        exampleAperture % store template aperture
        PSF; % store custom PSF
        PSF_local % store template PSF
        isCustomMode = false; % if custom aperture is one
        isExampleLoaded = false; % if template aperture is on
        tempFig % used for drawing custom aperture
        isCustomAngle = false % if the spiders angles are taken from user input 
        spider_angles % spider angles vector
    end
    
    methods (Access = private)
        
        function updateTelescopePreview(app)
            % To display text
            app.CustomStatus.Value = "Drawing aperture ...";

            % Read UI States
            currentTelescopeType = app.TelescopeDropDown.Value;
            selectedType = app.MirrorTypeGroup.SelectedObject.Text;
            is_hexagonal = strcmp(selectedType, 'Hexagonal');
        
            % Setup UIAxes
            cla(app.temp_aperture);
            hold(app.temp_aperture, 'on');
            axis(app.temp_aperture, 'equal');
            
            % Set the background to black
            app.temp_aperture.Color = 'k';
            app.temp_aperture.XColor = 'none';
            app.temp_aperture.YColor = 'none';
            
            % Capture the exact background color to use later.
            % This ensures the obstruction and spiders match perfectly.
            bgColor = app.temp_aperture.Color;
        
            % NORMALIZED GEOMETRY
            R_outer = 1.0;  
            
            % OBSTRUCTION
            if app.ObstructionCheckBox.Value
                ratio = app.DiameterObstructionSlider.Value / 100;
                ratio = min(max(ratio, 0), 0.9);
                R_inner = R_outer * ratio;
            else
                R_inner = 0;
            end
        
            % PRIMARY MIRROR DRAWING
            if is_hexagonal
                % SEGMENTED MIRROR LOGIC
                
                % DIRECT INPUT: Number of Rings
                num_rings = app.NumberofRingsEditField.Value;
             
                % Calculate the gap value distance
                gap_value = app.SegmentDistanceSlider.Value * 0.01; 
        
                % MATH: Determine Segment Size
                % The mirror radius (R_outer) spans exactly 'num_rings' segments + half a center segment.
                % Hex height units: Each ring adds 1.5 units, plus the center (1.0).
                % But usually, we approximate the "Radius" as N rings.
                % Precise packing radius: 
                segment_radius = R_outer / (num_rings * 1.5 + 1); 
                
                % Drawing parameters
                r_draw = max(0.001, segment_radius - gap_value/2);
                % hex_pitch is the vertical spacing distance (sqrt(3) * radius)
                hex_pitch = segment_radius * sqrt(3); 
                
                % Grid Limits
                % Determine how far the loop needs to go to cover the aperture
                if strcmp(currentTelescopeType, 'ELT')
                    grid_limit = num_rings + 2;  % ELT needs a wider search area for its circular cut
                else
                    grid_limit = num_rings; % Standard hex mirrors are defined exactly by ring count
                end

                % Hexagon Vertex Angles 0:60:360 defines "Flat Top" orientation (rotated 90deg from pointy-top)
                theta = 0:60:360; 
                hex_cos = cosd(theta);
                hex_sin = sind(theta);
                
                % Loop over the hexagonal grid coordinates (q, r)
                for q = -grid_limit:grid_limit
                    for r = -grid_limit:grid_limit
                        % Coordinate Transformation: Axial (q,r) -> Cartesian (x,y)
                        % cx handles the "Stacking" (1.5 step) -> Horizontal spacing
                        % cy handles the "Staggering" (sqrt(3) step) -> Vertical spacing
                        cx = segment_radius * 1.5 * q;
                        cy = hex_pitch * (r + q/2);
                        
                        % --- ROTATION FIX END ---
                        dist = sqrt(cx^2 + cy^2); 
                        should_draw = false;
                        is_obstructed = false;
                        
                        % Check Central Obstruction
                        if app.ObstructionCheckBox.Value
                            % If segment is inside the inner hole radius, mark as obstructed
                             if dist < (R_inner + segment_radius * 0.1)
                                 is_obstructed = true;
                             end
                        end

                        % Check Outer Boundary
                        if ~is_obstructed
                            if strcmp(currentTelescopeType, 'ELT')
                                % Circular Cutout for ELT
                                % Keep segment if it fits inside the outer radius
                                if dist <= (R_outer - segment_radius * 0.2)
                                    should_draw = true;
                                end
                            else
                                % Standard Hex Case: Hexagonal Cutout
                                % Keep segment if axial coordinates are within ring limit
                                if abs(q) <= num_rings && abs(r) <= num_rings && abs(q+r) <= num_rings
                                    should_draw = true;
                                end
                            end
                        end

                        % Draw the valid segment
                        if should_draw
                            vx = cx + r_draw * hex_cos;
                            vy = cy + r_draw * hex_sin;
                            patch(app.temp_aperture, vx, vy, 'w', 'EdgeColor', 'none');
                        end
                    end
                end
                
            else
                % CIRCULAR MIRROR LOGIC
                % Draw the main white circular mirror
                pos_outer = [-R_outer, -R_outer, 2*R_outer, 2*R_outer];
                rectangle(app.temp_aperture, 'Position', pos_outer, 'Curvature', [1 1], ...
                    'FaceColor', 'w', 'EdgeColor', 'none');
        
                % Draw Black Hole (Central Obstruction)
                if app.ObstructionCheckBox.Value && R_inner > 0
                    D_inner = R_inner * 2;
                    pos_inner = [-R_inner, -R_inner, D_inner, D_inner];
                    % % USE bgColor here to cut out the center visually
                    rectangle(app.temp_aperture, 'Position', pos_inner, 'Curvature', [1 1], ...
                        'FaceColor', bgColor, 'EdgeColor', 'none');
                end
            end
        
            % SPIDER LEGS LOGIC
            if app.SpidersCheckBox.Value
                spider_thick_ratio = app.SpiderThicknessSlider.Value;
                % Check if using Custom Angles or Automatic Preset
                if app.isCustomAngle
                    app.spider_angles = str2num(app.SpiderAngle.Value);
                else
                    % Sync the text box with the current automatic angles
                    app.SpiderAngle.Value = num2str(app.spider_angles);
                end

                % Loop through each spider leg angle
                for i = 1:length(app.spider_angles)
                    angle = app.spider_angles(i);
                    
                    % Geometry calculations for the leg rectangle
                    start_r = max(0, R_inner - 0.1); 
                    end_r = R_outer;
                    hw = spider_thick_ratio / 2; 
                    
                    % Define rectangle coordinates (unrotated)
                    X_arm = [start_r, end_r, end_r, start_r];
                    Y_arm = [-hw, -hw, hw, hw];
                    
                    % Rotate the rectangle to the specific angle
                    R_mat = [cosd(angle) -sind(angle); sind(angle) cosd(angle)];
                    coords = R_mat * [X_arm; Y_arm];
                    
                   % Draw the leg using background color
                    patch(app.temp_aperture, coords(1, :), coords(2, :), bgColor, 'EdgeColor', 'none');
                end
            end
            % Finalize: Capture mask and update physics plots
            captureApertureFromPlot(app);
            plot_custom(app);
            app.CustomStatus.Value = 'Done';
        end
        
        function updateInterfaceState(app)
        % Manages the Enabled/Disabled state of UI components based on user selection
            % MASTER LOCK CHECK
            currentSelection = app.TelescopeDropDown.Value;
            % If no telescope is selected, disable everything and clear plots
            if strcmp(currentSelection, 'Select Telescope...')
                app.isCustomMode = false;
                % Disable all controls
                app.MirrorTypeGroup.Enable = 'off';
                app.ObstructionCheckBox.Enable = 'off';
                app.DiameterObstructionSlider.Enable = 'off';
                app.NumberofRingsEditField.Enable = 'off'; % Changed from Segments
                app.SegmentDistanceSlider.Enable = 'off';
                app.SpidersCheckBox.Enable = 'off';
                app.SpiderThicknessSlider.Enable = 'off';
                app.Numberspiderseditfield.Enable = 'off';
                app.SpiderAngle.Enable = 'off';

                app.label_custom_aperture.Enable = 'off';
                app.label_customPSF.Enable = 'off';
                app.label_3D.Enable = 'off';

                app.CustomStatus.Value = '';


                cla(app.custom_aperture);
                cla(app.custom_PSF);
                cla(app.custom_profile);
                cla(app.PSF_3D);

                return;
            end
        
            % CUSTOM MODE LOGIC
            if strcmp(currentSelection, 'CUSTOM')
                % Unlock the Mirror Type Group so user can choose
                app.MirrorTypeGroup.Enable = 'on';
                
                % User must select Geometry before unlocking other controls
                if isempty(app.MirrorTypeGroup.SelectedObject)
                    % No geometry selected -> Keep controls locked
                    app.ObstructionCheckBox.Enable = 'off';
                    app.DiameterObstructionSlider.Enable = 'off';
                    app.NumberofRingsEditField.Enable = 'off';
                    app.SegmentDistanceSlider.Enable = 'off';
                    app.SpidersCheckBox.Enable = 'off';
                    app.SpiderThicknessSlider.Enable = 'off';
                else
                    % Geometry selected -> Unlock main toggles
                    app.ObstructionCheckBox.Enable = 'on';
                    app.SpidersCheckBox.Enable = 'on';
                end
            else
                % PRESET MODES (Hubble, ELT, etc.)
                % Lock the geometry selection (User shouldn't change ELT to Circular)
                app.MirrorTypeGroup.Enable = 'off';
                
                % Unlock universal controls
                app.ObstructionCheckBox.Enable = 'on';
                app.SpidersCheckBox.Enable = 'on';
            end
        
            % SPECIFIC COMPONENT RULES (Standard Logic)
            
            % Rule A: Segmentation Controls (Only for Hexagonal)
            % Only enable Ring/Gap controls if Hexagonal geometry is active
            selectedType = app.MirrorTypeGroup.SelectedObject.Text; 
            
            if strcmp(selectedType, 'Hexagonal')
                app.NumberofRingsEditField.Enable = 'on';
                app.SegmentDistanceSlider.Enable = 'on';
            else
                app.NumberofRingsEditField.Enable = 'off';
                app.SegmentDistanceSlider.Enable = 'off';
            end
            
            % Rule B: Sub-controls (Obstruction/Spiders)
            % Only enable sliders if the parent CheckBox is active
            if app.ObstructionCheckBox.Value
                app.DiameterObstructionSlider.Enable = 'on';
            else
                app.DiameterObstructionSlider.Enable = 'off';
            end
            
            if app.SpidersCheckBox.Value
                app.SpiderThicknessSlider.Enable = 'on'; 
                app.Numberspiderseditfield.Enable = 'on';
                app.SpiderAngle.Enable = 'on';
            else
                app.SpiderThicknessSlider.Enable = 'off';
                app.Numberspiderseditfield.Enable = 'off';
                app.SpiderAngle.Enable = 'off';
            end

            
        end

        function captureApertureFromPlot(app)
            % Converts the vector graphics plot into a high-res raster image for physics calculation.
            % DEFINE EXACT RESOLUTION
            desiredSize = app.n; 
            
            % SIZE CONTROL
            % Ratio determines padding
            apertureRatio = 0.222; 
        
            % CREATE INVISIBLE STUDIO
            % Create a hidden figure to handle the rendering without affecting the UI
            app.tempFig = figure('Visible', 'off', 'Color', 'k');
            
            %  COPY THE AXES
            % Clone the preview axis into the hidden figure
            newAx = copyobj(app.temp_aperture, app.tempFig);
            
            % Clean up visual artifacts
            title(newAx, '');
            newAx.XColor = 'none';
            newAx.YColor = 'none';
            newAx.Color = 'k'; 
            
            % POSITION AXES (CENTERED)
            newAx.Units = 'normalized';
            
            % Calculate margins to center the aperture with the specific ratio
            margin = (1 - apertureRatio) / 2;
            
            % Position = [Left, Bottom, Width, Height]
            newAx.Position = [margin, margin, apertureRatio, apertureRatio];
            
            % SETUP VIRTUAL PAPER
            app.tempFig.PaperUnits = 'inches';
            app.tempFig.PaperPosition = [0, 0, 10, 10]; 
            app.tempFig.PaperPositionMode = 'manual';
            app.tempFig.InvertHardcopy = 'off'; 
            
            % Calculate DPI
            dpi = round(desiredSize / 10);
            
            % PRINT TO FILE
            % Use 'print' to rasterize the vector plot to a PNG file
            print(app.tempFig, 'temp_aperture.png', '-dpng', ['-r' num2str(dpi)]);
            
            % LOAD AND CLEANUP
            try
                % Read the generated image into the app property
                app.aperture = imread('temp_aperture.png');
                delete('temp_aperture.png');
            catch
                return;
            end
            
        end

        function plot_custom(app)
            % Handles the calculation and visualization of the Custom Telescope

            % Clear previous plot
            cla(app.custom_aperture)

            % Crop the center of the aperture to show a "Zoomed In" view for the UI
            image = app.aperture;
            Radius = app.n/9;
            image = image(app.n/2-Radius-25:app.n/2+Radius+25,app.n/2-Radius-25:app.n/2+Radius+25);
            imshow(image,'parent',app.custom_aperture);
            
            % Adjust resolution based on Wavelength ratio
            % Higher wavelength = Smaller effective aperture = Broader PSF
            scale_factor = app.WavelengthEditField.Value / app.lambda_ref;
            
            % Resize the binary mask for the physics calculation
            aper = imresize(app.aperture,[app.N/scale_factor app.N/scale_factor]);
            aper = im2gray(aper);
            aper = aper/max(max(aper));


            % PSF calculation
            % Compute the diffraction pattern using Fast Fourier Transform
            field_ref = fftshift(fft2(fftshift(aper)));
            PSF_ref = abs(field_ref).^2;
            % Normalize result so peak intensity is 1.0
            app.PSF = PSF_ref / max(PSF_ref(:));
            
            % VIEW SETTINGS (Log vs Linear)
            if app.LogButton.Value
                view = log(app.PSF + 1e-7);
            else
                view = app.PSF;
            end

            % Update UI Labels if active
            if app.isCustomMode
                app.label_custom_aperture.Enable = 'on';
                app.label_customPSF.Enable = 'on';
                app.label_3D.Enable = 'on';
            end
            

            % PLOT 2D PSF
            imagesc(view,'Parent', app.custom_PSF)
            axis(app.custom_PSF, 'square');
            colormap(app.custom_PSF, app.ColormapsButtonGroup.SelectedObject.Text);
            newSize = size(aper, 2);
            app.custom_PSF.XLim = [1 newSize];
            app.custom_PSF.YLim = [1 newSize];

            % CONTRAST ADJUSTMENT
            % Adjust the black level based on the slider to hide/show faint details
            vmin = min(view(:));
            vmax = max(view(:));
            if app.ContrastSlider.Value > 0
                clim(app.custom_PSF, [vmin*app.ContrastSlider.Value vmax]);
            end
            
            
            % INTENSITY PROFILE
            % Extract and plot the central horizontal cross-section
            profile = app.PSF(:,round(newSize/2));
            app.custom_profile.XLim = [1 newSize];
            plot(app.custom_profile,1:newSize,profile,'black');
            axis(app.custom_profile, 'off');
            

            % 3D SURFACE PLOT
            % Downsample the data (step size) to keep the 3D plot responsive
            target_res = 100; 
            step = max(1, floor(size(view, 1) / target_res));
            Z_lowRes = view(1:step:newSize, 1:step:newSize);
            surf(app.PSF_3D,Z_lowRes);
            colormap(app.PSF_3D, app.ColormapsButtonGroup.SelectedObject.Text);
            grid(app.PSF_3D, 'on');
            axis(app.PSF_3D, 'tight');
        end
         
        function plot_template(app)
            % Handles the calculation and visualization of the Pre-Loaded Templates (Hubble, JWST, etc.)
            
            % APERTURE PREVIEW
            image = app.exampleAperture;
            [rows, cols, num_wavelengths] = size(image);
            cla(app.template_aperture)

            % Check if image needs cropping for the preview box
            if rows ~= app.n
                image = app.exampleAperture;
            else
                image = imresize(app.exampleAperture,[app.n app.n]);
                Radius = app.n/9;
                image = image(app.n/2-Radius-25:app.n/2+Radius+25,app.n/2-Radius-25:app.n/2+Radius+25);
            end
            imshow(image,'parent',app.template_aperture);
            
            % PHYSICS SCALING
            scale_factor = app.WavelengthEditField.Value / app.lambda_ref;
            
            % Resize based on wavelength and normalize
            aper = imresize(app.exampleAperture,[app.N/scale_factor app.N/scale_factor]);
            aper = im2gray(aper);
            aper = aper/max(max(aper));


            % PSF calculation
            field_ref = fftshift(fft2(fftshift(aper)));
            PSF_ref = abs(field_ref).^2;
            app.PSF_local = PSF_ref / max(PSF_ref(:));
            
            % VIEW SETTINGS
            if app.LogButton.Value
                view = log(app.PSF_local + 1e-7);
            else
                view = app.PSF_local;
            end

            if app.isExampleLoaded
                app.label_template_aperture.Enable = 'on';
                app.label_templatePSF.Enable = 'on';
            end

            % PLOT 2D PSF (Template Axis)
            imagesc(view,'Parent', app.template_PSF)
            axis(app.template_PSF, 'square');
            colormap(app.template_PSF, app.ColormapsButtonGroup.SelectedObject.Text);
            newSize = size(aper, 2);
            app.template_PSF.XLim = [1 newSize];
            app.template_PSF.YLim = [1 newSize];
            

            % Contrast
            vmin = min(view(:));
            vmax = max(view(:));
            if app.ContrastSlider.Value > 0
                clim(app.template_PSF, [vmin*app.ContrastSlider.Value vmax]);
            end
            
            
            % INTENSITY PROFILE
            % Use the local PSF variable for the profile
            profile = app.PSF_local(:,round(newSize/2));
            app.template_profile.XLim = [1 newSize];
            plot(app.template_profile,1:newSize,profile,'black');
            axis(app.template_profile, 'off');
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Force the dropdown to the neutral state
            app.TelescopeDropDown.Value = 'Select Telescope...';
        
            % Lock everything down
            updateInterfaceState(app);
            
            % Set slider to reference wavelength
            app.WavelengthSlider.Value = app.lambda_ref;
            app.WavelengthEditField.Value = app.lambda_ref;
            
        end

        % Value changed function: TelescopeDropDown
        function TelescopeDropDownValueChanged(app, event)
            % Handles the selection of a telescope preset or Custom mode from the dropdown.
            value = app.TelescopeDropDown.Value;
            
            % If the user selects the placeholder text, lock the interface and do nothing.
            if strcmp(value, 'Select Telescope...')
                updateInterfaceState(app);
            else
                % Activate custom mode behavior (enabling specific UI elements)
                app.isCustomMode = true;
                % Reset the "Manual Angle" flag so presets load their factory angle not the last user-typed values.
                app.isCustomAngle = false;
        
                switch value
                    case 'CUSTOM'
                        % Reset Mirror Geometry to a safe default (Circular)
                        app.MirrorTypeGroup.SelectedObject = app.CircularButton;
    
                        % Reset Parameters to "Blank Slate" defaults
                        app.NumberofRingsEditField.Value = 1;
                        app.SegmentDistanceSlider.Value = 0; 
                        app.ObstructionCheckBox.Value = false;
                        app.SpidersCheckBox.Value = false;
                        % Clear any previous manual angle text
                        app.SpiderAngle.Value = '';
                        % Get the number from the UI (and ensure it's a clean integer)
                        n_legs = round(app.Numberspiderseditfield.Value);
                        n_legs = max(1, n_legs); % Safety: Minimum 1 leg
                        
                        % Generate angles: (0, 360/n, 2*360/n, ...)
                        app.spider_angles = (0 : n_legs-1) * (360 / n_legs);
                    case 'ELT'
                        % Geometry: Hexagonal, massive segmentation
                        app.MirrorTypeGroup.SelectedObject = app.HexagonalButton;
                        
                        % Physical Params: ~800 segments (approx 16 rings)
                        app.NumberofRingsEditField.Value = 16;
                        app.SegmentDistanceSlider.Value = 0.05; 
                        
                        % Obstruction: Large secondary tower (approx 28% of diameter)
                        app.ObstructionCheckBox.Value = true;
                        app.DiameterObstructionSlider.Value = 28.3; % 11.1m / 39.3m
    
                        % Spiders: 6 legs, Hexagonal symmetry (30, 90, 150...)
                        app.SpidersCheckBox.Value = true;
                        app.SpiderThicknessSlider.Value = 0.015;
                        app.Numberspiderseditfield.Value = 6;
                        app.spider_angles = [30, 90, 150, 210, 270, 330];
            
                    case 'HUBBLE'
                        % Geometry: Monolithic Circular Primary
                        app.MirrorTypeGroup.SelectedObject = app.CircularButton;
                        
                        % Obstruction: Secondary mirror assembly
                        app.ObstructionCheckBox.Value = true;
                        app.DiameterObstructionSlider.Value = 35; % 0.3m / 2.4m
                        % Spiders: 4 legs in a standard Cross pattern
                        app.SpidersCheckBox.Value = true;
                        app.SpiderThicknessSlider.Value = 0.02;
                        app.Numberspiderseditfield.Value = 4;
                        app.spider_angles = [0, 90, 180, 270];
                    case 'JWST'
                        % Geometry: Hexagonal, 18 Segments (2 Rings)
                        app.MirrorTypeGroup.SelectedObject = app.HexagonalButton;
                        
                        app.NumberofRingsEditField.Value = 2;
                        app.SegmentDistanceSlider.Value = 0.1; 
                        
                        % Obstruction (12% - Small secondary)
                        app.ObstructionCheckBox.Value = true;
                        app.DiameterObstructionSlider.Value = 11.4; % 0.74m / 6.5m
                        % Spiders: Unique "Tripod" configuration
                        app.SpidersCheckBox.Value = true;
                        app.SpiderThicknessSlider.Value = 0.02;
                        app.Numberspiderseditfield.Value = 3;
    
                        app.spider_angles = [90, 240, 300];
            
                    case 'KECK'
                        % Geometry: Hexagonal, 36 Segments (3 Rings)
                        app.MirrorTypeGroup.SelectedObject = app.HexagonalButton;
                        
                        app.NumberofRingsEditField.Value = 3;
                        app.SegmentDistanceSlider.Value = 0.1;
                        
                        % Obstruction (18% - Standard for 10m class)
                        app.ObstructionCheckBox.Value = true;
                        app.DiameterObstructionSlider.Value = 18.0; 
                        % Spiders: 6 legs aligned with hex vertices
                        app.SpidersCheckBox.Value = true;
                        app.SpiderThicknessSlider.Value = 0.02;
                        app.Numberspiderseditfield.Value = 6;
                        app.spider_angles = [0,60,120,180,240,300];
                end

                % Unlock/Lock UI controls based on the new selection
                updateInterfaceState(app);
                % Redraw the aperture preview
                updateTelescopePreview(app);
            end
        end

        % Value changed function: WavelengthSlider
        function WavelengthSliderValueChanged(app, event)
            % Handles updates when the wavelength slider is moved.
            value = app.WavelengthSlider.Value;
            app.WavelengthEditField.Value = value;
            
            % Re-calculate the physics (PSF) for any active plots.
            if app.isCustomMode
                plot_custom(app);
            end
            % Update template PSF plot too
            if app.isExampleLoaded
                plot_template(app);
            end

        end

        % Value changed function: WavelengthEditField
        function WavelengthEditFieldValueChanged(app, event)
           % Check if the user entered a value outside the supported range (400nm - 2400nm) 
            if app.WavelengthEditField.Value > 2400 || app.WavelengthEditField.Value < 400
                uialert(app.UIFigure, ...
                        'Inconsistent wavelength','Success');
            else
                % If valid, move the slider to match the typed number.
                value = app.WavelengthEditField.Value;
                app.WavelengthSlider.Value = value;
                % Update the Custom Telescope view
                plot_custom(app)
                % Update the Template view (if active) for side-by-side comparison
                if app.isExampleLoaded
                    plot_template(app)
                end            
            end
        end

        % Button pushed function: SavepresetButton
        function SavepresetButtonPushed(app, event)
            % 1. VALIDATION
            if strcmp(app.TelescopeDropDown.Value, 'Select Telescope...') || strcmp(app.TelescopeDropDown.Value, 'ELT')
                uialert(app.UIFigure, 'The design cannot be saved', 'Save Error');
            else
        
                % GATHER DATA
                designData = struct();
                
                % We save the original name just for reference
                designData.OriginalType = app.TelescopeDropDown.Value; 
                
                if isempty(app.MirrorTypeGroup.SelectedObject)
                    designData.MirrorType = 'None';
                else
                    designData.MirrorType = app.MirrorTypeGroup.SelectedObject.Text;
                end
                
                %Primary Mirror
                designData.NumRings = app.NumberofRingsEditField.Value; 
                designData.SegmentGap = app.SegmentDistanceSlider.Value;
                
                % Obstruction
                designData.HasObstruction = app.ObstructionCheckBox.Value;
                designData.ObstructionRatio = app.DiameterObstructionSlider.Value;
                
                % Spiders
                designData.HasSpiders = app.SpidersCheckBox.Value;
                designData.SpiderThickness = app.SpiderThicknessSlider.Value;
                designData.NumSpiders = app.Numberspiderseditfield.Value; 
                
                % We save the specific angles currently being used.
                % This ensures JWST's unique [90 240 300] is remembered.
                designData.SpiderAngles = app.SpiderAngle.Value;
            
                % SAVE TO FILE
                [file, path] = uiputfile('MyCustomTelescope.mat', 'Save Telescope Design');
                if isequal(file, 0), return; end
                
                fullPath = fullfile(path, file);
                save(fullPath, 'designData');
                uialert(app.UIFigure, 'Preset saved successfully!', 'Success', 'Icon', 'success');
            end
        end

        % Button pushed function: LoadpresetButton
        function LoadpresetButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Select a Design File');
            if isequal(file, 0), return; end
            
            % Load data
            data = load(fullfile(path, file));
            d = data.designData; 
            
            % FORCE "CUSTOM" MODE
            app.TelescopeDropDown.Value = 'CUSTOM';
            
            % Trigger the reset to clear the canvas
            TelescopeDropDownValueChanged(app, []); 
            
            % RESTORE GEOMETRY (Hex/Circ)
            if strcmp(d.MirrorType, 'Hexagonal')
                app.MirrorTypeGroup.SelectedObject = app.HexagonalButton;
            elseif strcmp(d.MirrorType, 'Circular')
                app.MirrorTypeGroup.SelectedObject = app.CircularButton;
            end
            
            % Unlock controls so we can edit them
            updateInterfaceState(app); 
            
            % RESTORE VALUES
            app.NumberofRingsEditField.Value = d.NumRings;
            app.SegmentDistanceSlider.Value = d.SegmentGap;
            
            app.ObstructionCheckBox.Value = d.HasObstruction;
            app.DiameterObstructionSlider.Value = d.ObstructionRatio;
            
            app.SpidersCheckBox.Value = d.HasSpiders;
            app.SpiderThicknessSlider.Value = d.SpiderThickness;
            app.Numberspiderseditfield.Value = d.NumSpiders;
            
            % Restore the specific angles 
            if isfield(d, 'SpiderAngles')
                app.SpiderAngle.Value = d.SpiderAngles;
                
                % Force the app to use these angles instead of calculating symmetric ones
                app.isCustomAngle = true; 
            end
            
            % FORCE UPDATE
            updateInterfaceState(app);
            updateTelescopePreview(app);
            
            msg = sprintf('Loaded design based on %s successfully!', d.OriginalType);
            uialert(app.UIFigure, msg, 'Success', 'Icon', 'success');
        end

        % Value changed function: DiameterObstructionSlider
        function DiameterObstructionSliderValueChanged(app, event)
            value = app.DiameterObstructionSlider.Value;
            if value
                % If checked: Make the slider active (bright)
                app.DiameterObstructionSlider.Enable = 'on';
            else
                % If unchecked: Deactivate the slider (greyed out)
                app.DiameterObstructionSlider.Enable = 'off';
            end
            
            % Update the image immediately (to show/hide the hole)
            updateTelescopePreview(app);
        end

        % Value changed function: ObstructionCheckBox
        function ObstructionCheckBoxValueChanged(app, event)
            % Update the rules (this will lock/unlock the slider)
            updateInterfaceState(app);
            
            % Update the image
            updateTelescopePreview(app);
        end

        % Value changed function: NumberofRingsEditField
        function NumberofRingsEditFieldValueChanged(app, event)
            updateTelescopePreview(app);
        end

        % Value changed function: SegmentDistanceSlider
        function SegmentDistanceSliderValueChanged(app, event)
            updateTelescopePreview(app);
        end

        % Selection changed function: MirrorTypeGroup
        function MirrorTypeGroupSelectionChanged(app, event)
            % Update the rules
            updateInterfaceState(app);
            
            % Update the image
            updateTelescopePreview(app);
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            % Handles updates when the Contrast Slider is moved.
            % Update custom and template view

            if app.isCustomMode
                plot_custom(app);
            end

            if app.isExampleLoaded
                plot_template(app);
            end
            
        end

        % Selection changed function: ColorscaleButtonGroup
        function ColorscaleButtonGroupSelectionChanged(app, event)
            % Handles updates when the color scale is changed

            % Update template and custom view
            if app.isCustomMode
                plot_custom(app);
            end

            if app.isExampleLoaded
                plot_template(app);
            end
        end

        % Selection changed function: ColormapsButtonGroup
        function ColormapsButtonGroupSelectionChanged(app, event)
            % Handles updates when the colormap is changed

            % Update template and custom view
            if app.isCustomMode
                plot_custom(app);
            end

            if app.isExampleLoaded
                plot_template(app);
            end
        end

        % Value changed function: SpidersCheckBox
        function SpidersCheckBoxValueChanged(app, event)
            % Handles the On/Off for the spiders
            % Resets custom angles
            app.isCustomAngle = false;

            % Update the rules (this will lock/unlock the slider)
            updateInterfaceState(app);
            updateTelescopePreview(app);
        end

        % Value changed function: SpiderThicknessSlider
        function SpiderThicknessSliderValueChanged(app, event)
            % Handles updates when the spider legs thickness is moved
            % Reset custom angles
            app.isCustomAngle = false;
            % Update template and custom view
            updateInterfaceState(app);
            updateTelescopePreview(app);
        end

        % Value changed function: Numberspiderseditfield
        function NumberspiderseditfieldValueChanged(app, event)
            % Handles the change of the number of spiders 
            app.isCustomAngle = false;
            % Get the number from the UI (and ensure it's a clean integer)
            n_legs = round(app.Numberspiderseditfield.Value);
            n_legs = max(1, n_legs); % Safety: Minimum 1 leg
            
            % Generate angles: (0, 360/n, 2*360/n, ...)
            app.spider_angles = (0 : n_legs-1) * (360 / n_legs);
            updateTelescopePreview(app);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % Handles the "Import" button to load an external aperture image (PNG, JPG, etc.).
            [Img,FilePath] = uigetfile({'*.png';'*.bmp';'*.jpg';'*.tiff'},'Select a file');
            if isnumeric(Img); return; end
            % Read the image from disk and store it in the app's template property.
            app.exampleAperture = imread(fullfile(FilePath, Img));
            app.isExampleLoaded = true;
            plot_template(app)

            app.TemplateStatus.Value = "You are using imported template.";
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            % Create the Help Window
            d = uifigure( ...
                'Name', 'Custom Panel Parameters', ...
                'Position', [377, 200, 520, 450], ... % Taller window to fit all parameters
                'Resize', 'off', ...
                'WindowStyle', 'modal'); % Blocks interaction with main app until closed
        
            % Define the Explanation Text (HTML formatted)
            helpContent = [ ...
                '<div style="font-family: Helvetica, Arial, sans-serif; font-size: 12px; line-height: 1.5; color: #222;">' ...
                ...
                '<b>1. Telescope (Dropdown)</b><br>' ...
                'Select a preset configuration (e.g., HST, ELT) to automatically load standard parameters for that observatory.<br><br>' ...
                ...
                '<b>2. Mirror Geometry</b><br>' ...
                '<ul>' ...
                '<li><b>Circular:</b> A standard monolithic primary mirror (like Hubble).</li>' ...
                '<li><b>Hexagonal:</b> A segmented mirror array composed of hexagonal segments (like JWST or ELT).</li>' ...
                '</ul>' ...
                ...
                '<b>3. Obstruction (Checkbox & Slider)</b><br>' ...
                'Controls the secondary mirror blockage. Use the checkbox to enable/disable it. ' ...
                'The slider adjusts the ratio of the obstruction diameter to the primary mirror diameter.<br><br>' ...
                ...
                '<b>4. Number of Rings (Integer)</b><br>' ...
                '<i>(Only for Hexagonal Geometry)</i> Defines the number of rings of segments around the central one. ' ...
                '0 = 1 segment; 1 = 7 segments; 2 = 19 segments; etc.<br><br>' ...
                ...
                '<b>5. Segment Distance (Slider)</b><br>' ...
                'Sets the physical gap size between adjacent hexagonal segments. Larger gaps act like diffraction gratings, creating satellite spots in the PSF.<br><br>' ...
                ...
                '<b>6. Spiders (Checkbox, Slider & Input)</b><br>' ...
                'The support vanes holding the secondary mirror. ' ...
                'The slider controls the <b>thickness</b> of the vanes. Thicker vanes create brighter diffraction spikes. The box defines the number of spiders.<br><br>' ...
                ...
                '<b>7. Angles (Text Field)</b><br>' ...
                'Enter the rotation angles for the spider vanes in degrees, separated by spaces or commas (e.g., <i>0, 90, 45</i>). ' ...
                '<b>Note:</b> If the angle is larger than 360, the app automatically understands as the remainder of 360 (modulo 360).' ...
                ...
                '</div>'
            ];
        
            % Create the HTML Component
            uihtml(d, ...
                'Position', [20, 60, 480, 380], ... % Padding for the text area
                'HTMLSource', helpContent);
        end

        % Image clicked function: Hubble
        function HubbleImageClicked(app, event)
            % Read template aperture
            app.exampleAperture = imread('template/HST.png');
            app.isExampleLoaded = true; % <--- Activate the flag
            plot_template(app);

            app.TemplateStatus.Value = "You are using HST template.";
        end

        % Image clicked function: ELT
        function ELTImageClicked(app, event)
            % Read template aperture
            app.exampleAperture = imread('template/ELT.png');
            app.isExampleLoaded = true; % <--- Activate the flag
            plot_template(app);

            app.TemplateStatus.Value = "You are using ELT template.";
        end

        % Image clicked function: Keck
        function KeckImageClicked(app, event)
            % Read template aperture
            app.exampleAperture = imread('template/Keck.png');
            app.isExampleLoaded = true; % <--- Activate the flag
            plot_template(app);

            app.TemplateStatus.Value = "You are using Keck template.";
        end

        % Image clicked function: JWST
        function JWSTImageClicked(app, event)
            % Read template aperture
            app.exampleAperture = imread('template/JWST.png');
            app.isExampleLoaded = true; % <--- Activate the flag
            plot_template(app);

            app.TemplateStatus.Value = "You are using JWST template.";
        end

        % Image clicked function: VLT
        function VLTImageClicked(app, event)
            % Read template aperture
            app.exampleAperture = imread('template/VLT.png');
            app.isExampleLoaded = true; % <--- Activate the flag
            plot_template(app);

            app.TemplateStatus.Value = "You are using VLT template.";
        end

        % Image clicked function: GMT
        function GMTImageClicked(app, event)
            % Read template aperture
            app.exampleAperture = imread('template/GMT.png');
            app.isExampleLoaded = true; % <--- Activate the flag
            plot_template(app);

            app.TemplateStatus.Value = "You are using GMT template.";
        end

        % Image clicked function: logo
        function logoImageClicked(app, event)

            d = uifigure( ...
                'Name', 'About ELT-PSF[edu]', ...
                'Position', [377.5 309.5 520 230], ...
                'Resize', 'off', ...
                'WindowStyle', 'modal');
            
            % Main Description Paragraph
            msg_desc = [
                "ELT-PSF[edu] is an education software developed by master students (Andres Santiago Villares Guanga, Mai Nhu Tin, Catalina Villegas Montoya) from " + ...
                "the Master in Astrophysics and Space Science (MASS, 2024–2026) program at Université " + ...
                "Côte d’Azur."
            ];
            
            uilabel(d, ...
                'Position', [20 130 480 80], ... 
                'Text', msg_desc, ...
                'HorizontalAlignment', 'left', ...
                'VerticalAlignment', 'top', ...
                'WordWrap', 'on');
            
            % --- THE SPLIT LINE ---
            
            % The static text (Black, non-clickable)
            uilabel(d, ...
                'Position', [20 110 130 22], ... % Width just enough for text
                'Text', 'For more information:', ...
                'HorizontalAlignment', 'left');
            
            % The Link ONLY (Blue, clickable)
            l = uihyperlink(d);
            l.Position = [155 110 300 22]; 
            l.Text = 'https://www.master-mass.eu/';
            l.URL = 'https://www.master-mass.eu/';
            l.FontColor = [0 0 1]; % Blue
            
            
            % Copyright Label
            uilabel(d, ...
                'Position', [20 80 480 20], ...
                'Text', "© 2025 Université Côte d’Azur. All rights reserved.", ...
                'HorizontalAlignment', 'left');
            
        end

        % Button pushed function: SaveAperture
        function SaveApertureButtonPushed(app, event)

            if strcmp(app.TelescopeDropDown.Value, 'Select Telescope...')
                uialert(app.UIFigure, 'You need to draw something first.', 'Save Error');
            else          
                [filename, pathname] = uiputfile('MyCustomTelescope.png', 'Save Aperture As');
            
                % Check if the user clicked "Cancel"
                if isequal(filename, 0) || isequal(pathname, 0)
                    return; % Stop execution
                end
            
                % Construct the full file path
                fullFilePath = fullfile(pathname, filename);
                
                try
                    dpi = round(app.n / 10);
                    print(app.tempFig, fullFilePath, '-dpng', ['-r' num2str(dpi)]);
                    uialert(app.UIFigure, ...
                            'Aperture saved!', ...
                            'Success', ...
                            'Icon', 'success');
                catch ME
                    uialert(app.UIFigure, ME.message, 'Error Saving File');
                end
            end
        end

        % Value changed function: SpiderAngle
        function SpiderAngleValueChanged(app, event)
            % Handles manual user input for specific spider angles
            
            app.isCustomAngle = true;
            updateTelescopePreview(app)
                    
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
           % Clears the template view completely.
            app.isExampleLoaded = false;

            cla(app.template_aperture);
            cla(app.template_PSF);
            cla(app.template_profile)

            app.label_template_aperture.Enable = 'off';
            app.label_templatePSF.Enable = 'off';
            app.TemplateStatus.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.custom_profile = uiaxes(app.UIFigure);
            app.custom_profile.XTick = [];
            app.custom_profile.YTick = [];
            app.custom_profile.Visible = 'off';
            colormap(app.custom_profile, 'hot')
            app.custom_PSF = uiaxes(app.UIFigure);
            app.custom_PSF.XTick = [];
            app.custom_PSF.YAxisLocation = 'right';
            app.custom_PSF.YTick = [];
            app.custom_PSF.ZTick = [];
            app.custom_PSF.Visible = 'off';
            colormap(app.custom_PSF, 'hot')
            app.template_PSF = uiaxes(app.UIFigure);
            app.template_PSF.XTick = [];
            app.template_PSF.YAxisLocation = 'right';
            app.template_PSF.YTick = [];
            app.template_PSF.Visible = 'off';
            colormap(app.template_PSF, 'hot')
            app.PSF_3D = uiaxes(app.UIFigure);
            app.PSF_3D.XTick = [];
            app.PSF_3D.YAxisLocation = 'right';
            app.PSF_3D.YTick = [];
            app.PSF_3D.Visible = 'off';
            colormap(app.PSF_3D, 'jet')
            app.template_aperture = uiaxes(app.UIFigure);
            app.template_aperture.XTick = [];
            app.template_aperture.YAxisLocation = 'right';
            app.template_aperture.YTick = [];
            app.template_aperture.Visible = 'off';
            colormap(app.template_aperture, 'hot')
            app.template_profile = uiaxes(app.UIFigure);
            app.template_profile.XTick = [];
            app.template_profile.YTick = [];
            app.template_profile.Visible = 'off';
            colormap(app.template_profile, 'hot')
            app.custom_aperture = uiaxes(app.UIFigure);
            app.custom_aperture.XTick = [];
            app.custom_aperture.YAxisLocation = 'right';
            app.custom_aperture.YTick = [];
            app.custom_aperture.ZTick = [];
            app.custom_aperture.Visible = 'off';
            colormap(app.custom_aperture, 'hot')
            app.temp_aperture = uiaxes(app.UIFigure);
            app.temp_aperture.XTick = [];
            app.temp_aperture.YAxisLocation = 'right';
            app.temp_aperture.YTick = [];
            app.temp_aperture.ZTick = [];
            app.temp_aperture.Visible = 'off';
            colormap(app.temp_aperture, 'hot')
            app.TemplateStatus = uieditfield(app.UIFigure, 'text');
            app.TemplateStatus.Editable = 'off';
            app.CustomPanel = uipanel(app.UIFigure);
            app.CustomPanel.Title = 'Custom Panel';
            app.TelescopeDropDownLabel = uilabel(app.CustomPanel);
            app.TelescopeDropDownLabel.Text = 'Telescope';
            app.TelescopeDropDown = uidropdown(app.CustomPanel);
            app.TelescopeDropDown.Items = {'Select Telescope...', 'ELT', 'HUBBLE', 'KECK', 'JWST', 'CUSTOM'};
            app.TelescopeDropDown.ItemsData = {'Select Telescope...', 'ELT', 'HUBBLE', 'KECK', 'JWST', 'CUSTOM'};
            app.TelescopeDropDown.ValueChangedFcn = createCallbackFcn(app, @TelescopeDropDownValueChanged, true);
            app.TelescopeDropDown.Tooltip = {'Select a telescope mode'};
            app.TelescopeDropDown.Value = 'Select Telescope...';
            app.ObstructionCheckBox = uicheckbox(app.CustomPanel);
            app.ObstructionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ObstructionCheckBoxValueChanged, true);
            app.ObstructionCheckBox.Tooltip = {'Enable/Disable telescope central obstruction'};
            app.ObstructionCheckBox.Text = '';
            app.ObstructionLabel = uilabel(app.CustomPanel);
            app.ObstructionLabel.Text = 'Obstruction';
            app.DiameterObstructionSlider = uislider(app.CustomPanel);
            app.DiameterObstructionSlider.MajorTicks = [];
            app.DiameterObstructionSlider.MajorTickLabels = {''};
            app.DiameterObstructionSlider.ValueChangedFcn = createCallbackFcn(app, @DiameterObstructionSliderValueChanged, true);
            app.DiameterObstructionSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.DiameterObstructionSlider.Tooltip = {'Modify the size of the telescope central obstruction'};
            app.NumberofRingsLabel = uilabel(app.CustomPanel);
            app.NumberofRingsLabel.Text = 'Number of Rings';
            app.NumberofRingsEditField = uieditfield(app.CustomPanel, 'numeric');
            app.NumberofRingsEditField.Limits = [0 Inf];
            app.NumberofRingsEditField.RoundFractionalValues = 'on';
            app.NumberofRingsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofRingsEditFieldValueChanged, true);
            app.NumberofRingsEditField.Tooltip = {'Edit the number of rings'};
            app.SegmentDistanceSliderLabel = uilabel(app.CustomPanel);
            app.SegmentDistanceSliderLabel.Text = 'Segment Distance';
            app.SegmentDistanceSlider = uislider(app.CustomPanel);
            app.SegmentDistanceSlider.Limits = [0 1];
            app.SegmentDistanceSlider.MajorTicks = [];
            app.SegmentDistanceSlider.MajorTickLabels = {''};
            app.SegmentDistanceSlider.ValueChangedFcn = createCallbackFcn(app, @SegmentDistanceSliderValueChanged, true);
            app.SegmentDistanceSlider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.SegmentDistanceSlider.Tooltip = {'Modify the distance between segments'};
            app.MirrorTypeGroup = uibuttongroup(app.CustomPanel);
            app.MirrorTypeGroup.SelectionChangedFcn = createCallbackFcn(app, @MirrorTypeGroupSelectionChanged, true);
            app.MirrorTypeGroup.Tooltip = {'Select between Circular and Hexagonal mirror geometry'};
            app.CircularButton = uiradiobutton(app.MirrorTypeGroup);
            app.CircularButton.Text = 'Circular';
            app.CircularButton.Value = true;
            app.HexagonalButton = uiradiobutton(app.MirrorTypeGroup);
            app.HexagonalButton.Text = 'Hexagonal';
            app.MirrorgeometryLabel = uilabel(app.CustomPanel);
            app.MirrorgeometryLabel.Text = 'Mirror geometry';
            app.SpiderThicknessSlider = uislider(app.CustomPanel);
            app.SpiderThicknessSlider.Limits = [0 0.1];
            app.SpiderThicknessSlider.MajorTicks = [];
            app.SpiderThicknessSlider.ValueChangedFcn = createCallbackFcn(app, @SpiderThicknessSliderValueChanged, true);
            app.SpiderThicknessSlider.MinorTicks = [0 0.005 0.01 0.015 0.02 0.025 0.03 0.035 0.04 0.045 0.05 0.055 0.06 0.065 0.07 0.075 0.08 0.085 0.09 0.095 0.1];
            app.SpiderThicknessSlider.Tooltip = {'Modify the width of the telescope spider legs'};
            app.SpiderThicknessSlider.Value = 0.02;
            app.SpidersCheckBoxLabel = uilabel(app.CustomPanel);
            app.SpidersCheckBoxLabel.Text = 'Spiders';
            app.SpidersCheckBox = uicheckbox(app.CustomPanel);
            app.SpidersCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpidersCheckBoxValueChanged, true);
            app.SpidersCheckBox.Tooltip = {'Enable/Disable telescope spider legs'};
            app.SpidersCheckBox.Text = '';
            app.Numberspiderseditfield = uieditfield(app.CustomPanel, 'numeric');
            app.Numberspiderseditfield.Limits = [1 100];
            app.Numberspiderseditfield.RoundFractionalValues = 'on';
            app.Numberspiderseditfield.ValueChangedFcn = createCallbackFcn(app, @NumberspiderseditfieldValueChanged, true);
            app.Numberspiderseditfield.Tooltip = {'Edit the number of spider legs'};
            app.Numberspiderseditfield.Value = 1;
            app.SavepresetButton = uibutton(app.CustomPanel, 'push');
            app.SavepresetButton.ButtonPushedFcn = createCallbackFcn(app, @SavepresetButtonPushed, true);
            app.SavepresetButton.Tooltip = {'Save custom aperture parameters as Matlab format'};
            app.SavepresetButton.Text = 'Save preset';
            app.LoadpresetButton = uibutton(app.CustomPanel, 'push');
            app.LoadpresetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpresetButtonPushed, true);
            app.LoadpresetButton.Tooltip = {'Load parameters saved file'};
            app.LoadpresetButton.Text = 'Load preset';
            app.CustomStatus = uieditfield(app.CustomPanel, 'text');
            app.CustomStatus.Editable = 'off';
            app.SaveAperture = uibutton(app.CustomPanel, 'push');
            app.SaveAperture.ButtonPushedFcn = createCallbackFcn(app, @SaveApertureButtonPushed, true);
            app.SaveAperture.Tooltip = {'Save the aperture as image format that can be used in template mode'};
            app.SaveAperture.Text = 'Save Aperture';
            app.HelpButton = uibutton(app.CustomPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Tooltip = {'Details about each parameter in the software'};
            app.HelpButton.Text = 'Help';
            app.AnglesLabel = uilabel(app.CustomPanel);
            app.AnglesLabel.Text = 'Angles';
            app.SpiderAngle = uieditfield(app.CustomPanel, 'text');
            app.SpiderAngle.ValueChangedFcn = createCallbackFcn(app, @SpiderAngleValueChanged, true);
            app.SpiderAngle.Tooltip = {'Modify spider angles vector'};
            app.note = uilabel(app.CustomPanel);
            app.note.Text = 'The software might be slow, please be patient!';
            app.TemplatesPanel = uipanel(app.UIFigure);
            app.TemplatesPanel.Title = 'Templates';
            app.ImportButton = uibutton(app.TemplatesPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Tooltip = {'User can import telescope aperture in image format here'};
            app.ImportButton.Text = 'Import';
            app.Hubble = uiimage(app.TemplatesPanel);
            app.Hubble.ImageClickedFcn = createCallbackFcn(app, @HubbleImageClicked, true);
            app.Hubble.Tooltip = {'Hubble Space Telescope (HST)'};
            app.Hubble.ImageSource = fullfile(pathToMLAPP, 'HST.jpeg');
            app.ELT = uiimage(app.TemplatesPanel);
            app.ELT.ImageClickedFcn = createCallbackFcn(app, @ELTImageClicked, true);
            app.ELT.Tooltip = {'Extremely Large Telescope (ELT)'};
            app.ELT.ImageSource = fullfile(pathToMLAPP, 'ELT_6_Lasers3-16x10.jpg');
            app.Keck = uiimage(app.TemplatesPanel);
            app.Keck.ImageClickedFcn = createCallbackFcn(app, @KeckImageClicked, true);
            app.Keck.Tooltip = {'W. M. Keck Observatory (Keck)'};
            app.Keck.ImageSource = fullfile(pathToMLAPP, 'Keck.jpg');
            app.JWST = uiimage(app.TemplatesPanel);
            app.JWST.ImageClickedFcn = createCallbackFcn(app, @JWSTImageClicked, true);
            app.JWST.Tooltip = {'James Webb Space Telescope (JWST)'};
            app.JWST.ImageSource = fullfile(pathToMLAPP, 'JWST.png');
            app.VLT = uiimage(app.TemplatesPanel);
            app.VLT.ImageClickedFcn = createCallbackFcn(app, @VLTImageClicked, true);
            app.VLT.Tooltip = {'Very Large Telescope (VLT)'};
            app.VLT.ImageSource = fullfile(pathToMLAPP, 'VLT.jpg');
            app.GMT = uiimage(app.TemplatesPanel);
            app.GMT.ImageClickedFcn = createCallbackFcn(app, @GMTImageClicked, true);
            app.GMT.Tooltip = {'Giant Magellan Telescope (GMT)'};
            app.GMT.ImageSource = fullfile(pathToMLAPP, 'GMT.jpeg');
            app.Reset = uibutton(app.TemplatesPanel, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Tooltip = {'Reset settings related to template mode'};
            app.Reset.Text = 'Reset';
            app.WavelengthmumLabel = uilabel(app.UIFigure);
            app.WavelengthmumLabel.Text = 'Wavelength (nm) ';
            app.WavelengthSlider = uislider(app.UIFigure);
            app.WavelengthSlider.Limits = [400 2400];
            app.WavelengthSlider.ValueChangedFcn = createCallbackFcn(app, @WavelengthSliderValueChanged, true);
            app.WavelengthSlider.Tooltip = {'Slide to modify the wavelength. Range: 400 - 2400 (based on ELT specifications)'};
            app.WavelengthSlider.Value = 600;
            app.WavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WavelengthEditField.Limits = [400 2400];
            app.WavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthEditFieldValueChanged, true);
            app.WavelengthEditField.Tooltip = {'User can manually type the wavelength here'};
            app.WavelengthEditField.Value = 400;
            app.ColorscaleButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorscaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorscaleButtonGroupSelectionChanged, true);
            app.ColorscaleButtonGroup.Tooltip = {'Change between different color scales to visualize the PSF.'};
            app.ColorscaleButtonGroup.Title = 'Colorscale';
            app.LinearButton = uiradiobutton(app.ColorscaleButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.LogButton = uiradiobutton(app.ColorscaleButtonGroup);
            app.LogButton.Text = 'Log';
            app.ColormapsButtonGroup = uibuttongroup(app.UIFigure);
            app.ColormapsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColormapsButtonGroupSelectionChanged, true);
            app.ColormapsButtonGroup.Tooltip = {'Change between different colormaps to visualize the PSF'};
            app.ColormapsButtonGroup.Title = 'Colormaps';
            app.JetButton = uiradiobutton(app.ColormapsButtonGroup);
            app.JetButton.Text = 'Jet';
            app.JetButton.Value = true;
            app.HotButton = uiradiobutton(app.ColormapsButtonGroup);
            app.HotButton.Text = 'Hot';
            app.GrayButton = uiradiobutton(app.ColormapsButtonGroup);
            app.GrayButton.Text = 'Gray';
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.Limits = [0 2];
            app.ContrastSlider.MajorTicks = [];
            app.ContrastSlider.MajorTickLabels = {''};
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4];
            app.ContrastSlider.Step = 0.004;
            app.ContrastSlider.Tooltip = {'Slide to change the contrast'};
            app.ContrastSlider.Value = 1;
            app.logo = uiimage(app.UIFigure);
            app.logo.ImageClickedFcn = createCallbackFcn(app, @logoImageClicked, true);
            app.logo.Tooltip = {'Click to have more information'};
            app.logo.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.label_customPSF = uilabel(app.UIFigure);
            app.label_customPSF.Enable = 'off';
            app.label_customPSF.Text = 'Custom PSF';
            app.label_templatePSF = uilabel(app.UIFigure);
            app.label_templatePSF.Enable = 'off';
            app.label_templatePSF.Text = 'Template PSF';
            app.label_template_aperture = uilabel(app.UIFigure);
            app.label_template_aperture.Enable = 'off';
            app.label_template_aperture.Text = 'Template aperture';
            app.label_custom_aperture = uilabel(app.UIFigure);
            app.label_custom_aperture.Enable = 'off';
            app.label_custom_aperture.Text = 'Custom aperture';
            app.label_3D = uilabel(app.UIFigure);
            app.label_3D.Enable = 'off';
            app.label_3D.Tooltip = {'Low resolution 3D PSF of custom aperture'};
            app.label_3D.Text = 'Custom 3D PSF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ELT_PSF_V5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end